classdef SYSTEM_IDENTIFICATION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransferFunctionSystemIdentificationUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        PanelMain           matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        Switch              matlab.ui.control.Switch
        InitialStateLabel   matlab.ui.control.Label
        GridLayout6         matlab.ui.container.GridLayout
        DataListBox         matlab.ui.control.ListBox
        GridLayout5_3       matlab.ui.container.GridLayout
        LoadFileButton      matlab.ui.control.Button
        GridLayout5_4       matlab.ui.container.GridLayout
        SaveGainsButton     matlab.ui.control.Button
        GridLayout12        matlab.ui.container.GridLayout
        RemoveButton        matlab.ui.control.Button
        GridLayout5_5       matlab.ui.container.GridLayout
        ResetButton         matlab.ui.control.Button
        GridLayout5_6       matlab.ui.container.GridLayout
        OptimizeButton      matlab.ui.control.Button
        OptGain             matlab.ui.control.NumericEditField
        GridLayout11_25     matlab.ui.container.GridLayout
        BSlider             matlab.ui.control.Slider
        GridLayout13_16     matlab.ui.container.GridLayout
        BGain               matlab.ui.control.NumericEditField
        BaseGainLabel       matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        PlotTab             matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        UIAxes              matlab.ui.control.UIAxes
        HistoryTab          matlab.ui.container.Tab
        GridLayout10        matlab.ui.container.GridLayout
        TextArea            matlab.ui.control.TextArea
        GridLayout8         matlab.ui.container.GridLayout
        PanelInput1         matlab.ui.container.Panel
        GridLayout9         matlab.ui.container.GridLayout
        GridLayout11        matlab.ui.container.GridLayout
        K1Slider            matlab.ui.control.Slider
        GridLayout13        matlab.ui.container.GridLayout
        K1Gain              matlab.ui.control.NumericEditField
        K1Label             matlab.ui.control.Label
        GridLayout11_2      matlab.ui.container.GridLayout
        Wn1Slider           matlab.ui.control.Slider
        GridLayout13_5      matlab.ui.container.GridLayout
        Wn1Gain             matlab.ui.control.NumericEditField
        Wn1Label            matlab.ui.control.Label
        GridLayout11_3      matlab.ui.container.GridLayout
        Td1Slider           matlab.ui.control.Slider
        GridLayout13_7      matlab.ui.container.GridLayout
        Td1Gain             matlab.ui.control.NumericEditField
        Td1Label            matlab.ui.control.Label
        GridLayout11_4      matlab.ui.container.GridLayout
        Z1Slider            matlab.ui.control.Slider
        GridLayout13_6      matlab.ui.container.GridLayout
        Z1Gain              matlab.ui.control.NumericEditField
        Z1Label             matlab.ui.control.Label
        GridLayout11_13     matlab.ui.container.GridLayout
        NonlinearFunctionEditFieldLabel  matlab.ui.control.Label
        NonlinearFunction1  matlab.ui.control.EditField
        UIAxes4             matlab.ui.control.UIAxes
        PanelInput2         matlab.ui.container.Panel
        GridLayout9_2       matlab.ui.container.GridLayout
        GridLayout11_14     matlab.ui.container.GridLayout
        K2Slider            matlab.ui.control.Slider
        GridLayout13_8      matlab.ui.container.GridLayout
        K2Gain              matlab.ui.control.NumericEditField
        K2Label             matlab.ui.control.Label
        GridLayout11_15     matlab.ui.container.GridLayout
        Wn2Slider           matlab.ui.control.Slider
        GridLayout13_9      matlab.ui.container.GridLayout
        Wn2Gain             matlab.ui.control.NumericEditField
        Wn2Label            matlab.ui.control.Label
        GridLayout11_16     matlab.ui.container.GridLayout
        Td2Slider           matlab.ui.control.Slider
        GridLayout13_10     matlab.ui.container.GridLayout
        Td2Gain             matlab.ui.control.NumericEditField
        Td2Label            matlab.ui.control.Label
        GridLayout11_17     matlab.ui.container.GridLayout
        Z2Slider            matlab.ui.control.Slider
        GridLayout13_11     matlab.ui.container.GridLayout
        Z2Gain              matlab.ui.control.NumericEditField
        Z2Label             matlab.ui.control.Label
        GridLayout11_24     matlab.ui.container.GridLayout
        NonlinearFunctionEditFieldLabel_4  matlab.ui.control.Label
        NonlinearFunction2  matlab.ui.control.EditField
        UIAxes4_2           matlab.ui.control.UIAxes
        PanelInput3         matlab.ui.container.Panel
        GridLayout9_3       matlab.ui.container.GridLayout
        GridLayout11_19     matlab.ui.container.GridLayout
        K3Slider            matlab.ui.control.Slider
        GridLayout13_12     matlab.ui.container.GridLayout
        K3Gain              matlab.ui.control.NumericEditField
        K3Label             matlab.ui.control.Label
        GridLayout11_20     matlab.ui.container.GridLayout
        Wn3Slider           matlab.ui.control.Slider
        GridLayout13_13     matlab.ui.container.GridLayout
        Wn3Gain             matlab.ui.control.NumericEditField
        Wn3Label            matlab.ui.control.Label
        GridLayout11_21     matlab.ui.container.GridLayout
        Td3Slider           matlab.ui.control.Slider
        GridLayout13_14     matlab.ui.container.GridLayout
        Td3Gain             matlab.ui.control.NumericEditField
        Td3Label            matlab.ui.control.Label
        GridLayout11_22     matlab.ui.container.GridLayout
        Z3Slider            matlab.ui.control.Slider
        GridLayout13_15     matlab.ui.container.GridLayout
        Z3Gain              matlab.ui.control.NumericEditField
        Z3Label             matlab.ui.control.Label
        GridLayout11_23     matlab.ui.container.GridLayout
        NonlinearFunctionEditFieldLabel_3  matlab.ui.control.Label
        NonlinearFunction3  matlab.ui.control.EditField
        UIAxes4_3           matlab.ui.control.UIAxes
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        messages = {}; % Description
        datas
        param
        paramTemp
        initial
        ave_fit
        nonLinearFunction
        nonLinearFunctionTemp
    end


    methods (Access = private)
        
        function UpdatePlot(app)                
            
            data = app.datas.(string(app.DataListBox.Value));
            [y_sim, app.ave_fit, fit] = Simulate(app, app.paramTemp);
                        
            t = data.SamplingInstants;
            
            plot(app.UIAxes, t, data.y,'-r');
            hold(app.UIAxes, 'on'); % Hold on for app.UIAxes
            plot(app.UIAxes, t, y_sim,'-b');
            hold(app.UIAxes, 'off'); % Hold on for app.UIAxes
            
            title(app.UIAxes, data.user.title);
            legend(app.UIAxes, {'Y Actual', ['Y Simulation (Fit: ' num2str(fit) '%)']}, 'Location', 'best');
            
            
            plot(app.UIAxes4, t, data.u(:,1),'-r');
            plot(app.UIAxes4_2, t, data.u(:,2),'-r');
            plot(app.UIAxes4_3, t, data.u(:,3),'-r');
        end
        
        function [y_sim, ave_fit, fit] = Simulate(app, param)    
                        
            data = app.datas.(string(app.DataListBox.Value));
            
            % Get the size of iddata object
            [samples, numOutputs, numInputs] = size(data);
            N = numInputs * numOutputs;
            
            Ts = data.Ts;  % Sampling time
            S = samples + app.initial;  % Number of data points
            t = (0:S-1) * Ts;  % Time vector from 0 to (N-1)*Ts
            y_actual = data.y(:, 1);  % Output signal from iddata
        
            % initialize y_sim and error
            y_sim = zeros(S - app.initial, numOutputs);
            
            % Loop through each output
            for i = 1:numOutputs
                y_base = param(N*4 + i) * ones(S - app.initial, 1);  % Initialize output with base gain
                
                % Loop through each input
                for j = 1:numInputs
                    % Calculate the index for current transfer function parameters
                    n = (i - 1) * numInputs + j;
                    
                    % Create transfer function Gn
                    k = param(n*4 - 3);
                    omega_n = param(n*4 - 2);
                    zeta = param(n*4 - 1);
                    td = param(n*4);
                    
                    num = [k * omega_n^2];
                    den = [1, 2 * zeta * omega_n, omega_n^2];
                    G = tf(num, den, 'InputDelay', abs(td));
                    
                    
                    % Simulate response for each input
                    u_extended = [data.u(1,j) * ones(app.initial, 1); data.u(:,j)];  % Extend input with initial values
                    [y_temp, ~] = lsim(G, u_extended, t);  % Simulate response for input j
                    y_temp = y_temp(app.initial + 1:end);  % Trim initial values
        
                    
                    str = string(app.nonLinearFunctionTemp(j));
                    str = strrep(str, 'u1', 'data.u(:,1)');
                    str = strrep(str, 'u2', 'data.u(:,2)');
                    str = strrep(str, 'u3', 'data.u(:,3)');
                    str = strrep(str, '^', '.^');
                    F = eval(str);
                    
                    
                    % Add response to output
                    y_base = y_base + F .* y_temp;
                end
                % Store simulation result for output i
                y_sim(:, i) = y_base;
                
                     
            end
            % Assuming data1 and data2 are defined
            fit = 100 * (1 - norm(y_actual - y_sim) / norm(y_actual - mean(y_actual)));
            
            V = round(0.01 * samples);
            ave_fit = 100 * (1 - norm(y_actual(V:end) - y_sim(V:end)) / norm(y_actual(V:end) - mean(y_actual(V:end))));
       
        end
            
        function objective = ComputeError(app, param)
            % Call your simulate function with the given parameters
            [~, ave_fit, ~] = Simulate(app, param);
            objective = -ave_fit;
        end
        
          
        function UpdateSlider(app)
            app.NonlinearFunction1.Value = string(app.nonLinearFunctionTemp(1));
            app.NonlinearFunction2.Value = string(app.nonLinearFunctionTemp(2));
            app.NonlinearFunction3.Value = string(app.nonLinearFunctionTemp(3));
            
            % Loop over each parameter and update the corresponding slider and gain
            for i = 1:length(app.paramTemp)
                paramValue = app.paramTemp(i);
                
                % Choose gain value that keeps slider value within 0-10 range
                gainValues = [0.000001, 0.00001, 0.0001, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 10000, 100000, 1000000];
                sliderValue = 0;
                gainValue = 1;
                if paramValue ~= 0 
                    for gain = gainValues
                        potentialSliderValue = paramValue / gain;
                        if abs(potentialSliderValue) >= 1 && abs(potentialSliderValue) <= 10
                            sliderValue = abs(potentialSliderValue);
                            if potentialSliderValue>0
                                gainValue = gain;   
                            else
                                gainValue = -gain;   
                            end
                            break;
                        end
                    end
                end
                
                % Update the corresponding slider and gain based on the index
                switch i
                    case 1
                        app.K1Slider.Value = sliderValue;
                        app.K1Gain.Value = gainValue;
                    case 2
                        app.Wn1Slider.Value = sliderValue;
                        app.Wn1Gain.Value = gainValue;
                    case 3
                        app.Z1Slider.Value = sliderValue;
                        app.Z1Gain.Value = gainValue;
                    case 4
                        app.Td1Slider.Value = sliderValue;
                        app.Td1Gain.Value = gainValue;
                    case 5
                        app.K2Slider.Value = sliderValue;
                        app.K2Gain.Value = gainValue;
                    case 6
                        app.Wn2Slider.Value = sliderValue;
                        app.Wn2Gain.Value = gainValue;
                    case 7
                        app.Z2Slider.Value = sliderValue;
                        app.Z2Gain.Value = gainValue;
                    case 8
                        app.Td2Slider.Value = sliderValue;
                        app.Td2Gain.Value = gainValue;
                    case 9
                        app.K3Slider.Value = sliderValue;
                        app.K3Gain.Value = gainValue;
                    case 10
                        app.Wn3Slider.Value = sliderValue;
                        app.Wn3Gain.Value = gainValue;
                    case 11
                        app.Z3Slider.Value = sliderValue;
                        app.Z3Gain.Value = gainValue;
                    case 12
                        app.Td3Slider.Value = sliderValue;
                        app.Td3Gain.Value = gainValue;
                    case 13
                        app.BSlider.Value = sliderValue;
                        app.BGain.Value = gainValue;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.paramTemp = [repmat([0.025, 0.1, 0.5, 0], 1, 3), ...   % Parameters for G1, ..., Gn = [k, omega_n, zeta, td]
                            repmat([0], 1, 1)];   % Constant, initial, & number of transfer functions
            app.param = app.paramTemp;
            
            app.nonLinearFunctionTemp = ['1'; '1'; '1'];
            app.nonLinearFunction = app.nonLinearFunctionTemp;
            
            UpdateSlider(app)
            app.initial = 1000;  
          
        end

        % Value changed function: DataListBox
        function DataListBoxValueChanged(app, event)
            UpdatePlot(app)
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Open file selection dialog
            [file, path] = uigetfile('*.mat', 'Select a MAT-file', 'D:\Files\TA\Matlab2');
            
            % Check if a file was selected
            if file ~= 0
                % Generate a field name based on the file name
                [~, name, ~] = fileparts(file);  % Extract the base name without extension
            
                % Load the file
                loadedData = struct();
                loadedData = load(fullfile(path, file));  % Load the file into a struct
                
                % Check the variables in the loaded file
                if isfield(loadedData, 'data')  % Check if 'data' exists in the loaded file
                    data = loadedData.data;
                    underscore_positions = strfind(name, '_');  % Find positions of underscores
                    name = name(1:underscore_positions(3) - 1);  % Extract substring up to second underscore
                    if exist('datas', 'var') ~= 0 && isfield(app.DataListBox.Items, name)  % Check if 'name' not exists in 'datas'
                        app.messages{end+1} = '>> Iddata already on the list';
                    else
                        app.datas.(name) = data;
                        if exist('app.DataListBox.Items', 'var') == 0   % Check if 'name' already exists in 'datas'
                            app.DataListBox.Items = [app.DataListBox.Items, name];  % Store the name of the data in the list
                            
                            app.PanelInput1.Title = data.InputName{1};
                            app.PanelInput2.Title = data.InputName{2};
                            app.PanelInput3.Title = data.InputName{3};
                            app.UIAxes.YLabel.String = data.OutputName{1};
                        else
                            app.DataListBox.Items = [app.DataListBox.Items, name];  % Store the name of the data in the list
                        end
                        app.messages{end+1} = sprintf('>> Loaded iddata in %s\n', file);
                        app.DataListBox.Value = name;
                        UpdatePlot(app)
                    end
                elseif isfield(loadedData, 'param')  % Check if 'paramTemp' exists in the loaded file
                    app.paramTemp = loadedData.param;
                    app.param = app.paramTemp;
                    app.nonLinearFunctionTemp = loadedData.nonLinearFunction;
                    app.nonLinearFunction = app.nonLinearFunction;
                    app.messages{end+1} = sprintf('>> Loaded parameter in %s\n', file);
                    UpdatePlot(app)
                    UpdateSlider(app)
                else
                    app.messages{end+1} = '>> Invalid file';
                end
            else
                app.messages{end+1} = '>> No file selected';
            end
            
            % Update the TextArea with app.messages
            app.TextArea.Value = app.messages;
        end

        % Callback function: BGain, BSlider, K1Gain, K1Slider, 
        % K1Slider, K2Gain, K2Slider, K2Slider, K3Gain, K3Slider, 
        % K3Slider, NonlinearFunction1, NonlinearFunction2, 
        % NonlinearFunction3, Td1Gain, Td1Slider, Td1Slider, 
        % Td2Gain, Td2Slider, Td2Slider, Td3Gain, Td3Slider, 
        % Td3Slider, Wn1Gain, Wn1Slider, Wn1Slider, Wn2Gain, 
        % Wn2Slider, Wn2Slider, Wn3Gain, Wn3Slider, Wn3Slider, 
        % Z1Gain, Z1Slider, Z1Slider, Z2Gain, Z2Slider, Z2Slider, 
        % Z3Gain, Z3Slider, Z3Slider
        function ValueChanged(app, event)
            app.paramTemp = [app.K1Slider.Value*app.K1Gain.Value, app.Wn1Slider.Value*app.Wn1Gain.Value, app.Z1Slider.Value*app.Z1Gain.Value, app.Td1Slider.Value*app.Td1Gain.Value, ...
                       app.K2Slider.Value*app.K2Gain.Value, app.Wn2Slider.Value*app.Wn2Gain.Value, app.Z2Slider.Value*app.Z2Gain.Value, app.Td2Slider.Value*app.Td2Gain.Value, ...
                       app.K3Slider.Value*app.K3Gain.Value, app.Wn3Slider.Value*app.Wn3Gain.Value, app.Z3Slider.Value*app.Z3Gain.Value, app.Td3Slider.Value*app.Td3Gain.Value, ...
                       app.BSlider.Value*app.BGain.Value];          
                   
           app.nonLinearFunctionTemp = {app.NonlinearFunction1.Value, app.NonlinearFunction2.Value, app.NonlinearFunction3.Value};
                   
            UpdatePlot(app)
            app.messages{end+1} = '>> Parameter changed';
            app.TextArea.Value = app.messages;
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            % Use fminsearch to find the best-fit parameters
            app.paramTemp = fminsearch(@(param) ComputeError(app, param), app.paramTemp);
            UpdatePlot(app)
            UpdateSlider(app)
            
            app.messages{end+1} = '>> Parameter optimized';
            app.messages{end+1} = sprintf('Estimated Parameters1: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                  app.paramTemp(1), app.paramTemp(2), app.paramTemp(3), app.paramTemp(4));
            app.messages{end+1} = sprintf('Estimated Parameters2: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                  app.paramTemp(5), app.paramTemp(6), app.paramTemp(7), app.paramTemp(8));
            app.messages{end+1} = sprintf('Estimated Parameters3: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                  app.paramTemp(9), app.paramTemp(10), app.paramTemp(11), app.paramTemp(12));
            app.messages{end+1} = sprintf('Base Gain = %.4f, Average fit = %.4f', app.paramTemp(13), app.ave_fit);
            app.TextArea.Value = app.messages;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, 'On')
                app.initial = 1000;  
            else
                app.initial = 0;  
            end          
            UpdatePlot(app)
        end

        % Button pushed function: SaveGainsButton
        function SaveGainsButtonPushed(app, event)
            app.param = app.paramTemp;
            param = app.param;
            
            app.nonLinearFunction = app.nonLinearFunctionTemp;
            nonLinearFunction = app.nonLinearFunction;
            
            % Default location and file name
            defaultFileName = fullfile('param', [app.DataListBox.Value, '.mat']);
            defaultLocation = fullfile(pwd, defaultFileName);  % Current folder as default location
            
            % Use uigetfile to open a file dialog with default location and name
            [fileName, filePath] = uiputfile(defaultLocation, 'Save as');
            
            % Check if the user selected a file (not canceled)
            if fileName ~= 0
                % Combine the path and file name
                fullFileName = fullfile(filePath, fileName);
                                
                % Save the data to the selected .mat file
                save(fullFileName, 'param', 'nonLinearFunction');
                
                % Optionally, display a confirmation message
                disp(['Data saved to ', fullFileName]);
                app.messages{end+1} = sprintf('>> Parameter saved to %.4f', fullFileName);
                app.messages{end+1} = sprintf('Estimated Parameters1: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                      app.paramTemp(1), app.paramTemp(2), app.paramTemp(3), app.paramTemp(4));
                app.messages{end+1} = sprintf('Estimated Parameters2: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                      app.paramTemp(5), app.paramTemp(6), app.paramTemp(7), app.paramTemp(8));
                app.messages{end+1} = sprintf('Estimated Parameters3: K = %.4f, Wn = %.4f, Z = %.4f, Td = %.4f\n', ...
                                      app.paramTemp(9), app.paramTemp(10), app.paramTemp(11), app.paramTemp(12));
            else
                % Display a message if the user canceled the operation
                app.messages{end+1} = '>> Save operation was canceled';
            end
            app.TextArea.Value = app.messages;       
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.paramTemp = app.param;
            UpdatePlot(app)
            UpdateSlider(app)
            app.messages{end+1} = '>> Parameter reseted';
            app.TextArea.Value = app.messages;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            currentItems = app.DataListBox.Items;
            
            % Find the index of the item to remove based on the current selected value
            indexToRemove = find(strcmp(currentItems, app.DataListBox.Value));
            
            if ~isempty(indexToRemove)
                % If the item is found, remove it
                app.messages{end+1} = sprintf('>> Removed %s', app.DataListBox.Value);
                
                % Check if there are still items left
                if ~isempty(app.DataListBox.Items)
                    app.DataListBox.Value = app.DataListBox.Items{1};  % Set the first item as the selected value
                end
                
                % Remove the item from the ListBox
                app.DataListBox.Items(indexToRemove) = [];
            else
                % If no item was found, notify the user
                app.messages{end+1} = '>> Item is empty or not found';
            end
            UpdatePlot(app)
            % Update the TextArea with the messages
            app.TextArea.Value = app.messages; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransferFunctionSystemIdentificationUIFigure = uifigure('Visible', 'off');
            app.TransferFunctionSystemIdentificationUIFigure.Name = 'Transfer Function System Identification';
            app.GridLayout = uigridlayout(app.TransferFunctionSystemIdentificationUIFigure);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.PanelMain = uipanel(app.GridLayout7);
            app.PanelMain.Title = 'Main';
            app.GridLayout3 = uigridlayout(app.PanelMain);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Switch = uiswitch(app.GridLayout5, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.InitialStateLabel = uilabel(app.GridLayout5);
            app.InitialStateLabel.Text = 'Initial State';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.DataListBox = uilistbox(app.GridLayout6);
            app.DataListBox.Items = {};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @DataListBoxValueChanged, true);
            app.DataListBox.Value = {};
            app.GridLayout5_3 = uigridlayout(app.GridLayout3);
            app.LoadFileButton = uibutton(app.GridLayout5_3, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.GridLayout5_4 = uigridlayout(app.GridLayout3);
            app.SaveGainsButton = uibutton(app.GridLayout5_4, 'push');
            app.SaveGainsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGainsButtonPushed, true);
            app.SaveGainsButton.Text = 'Save Gains';
            app.GridLayout12 = uigridlayout(app.GridLayout3);
            app.RemoveButton = uibutton(app.GridLayout12, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.GridLayout5_5 = uigridlayout(app.GridLayout3);
            app.ResetButton = uibutton(app.GridLayout5_5, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayout5_6 = uigridlayout(app.GridLayout3);
            app.OptimizeButton = uibutton(app.GridLayout5_6, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.OptGain = uieditfield(app.GridLayout5_6, 'numeric');
            app.OptGain.Value = 0.5;
            app.GridLayout11_25 = uigridlayout(app.GridLayout3);
            app.BSlider = uislider(app.GridLayout11_25);
            app.BSlider.Limits = [0 10];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_16 = uigridlayout(app.GridLayout11_25);
            app.BGain = uieditfield(app.GridLayout13_16, 'numeric');
            app.BGain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.BGain.Value = 1;
            app.BaseGainLabel = uilabel(app.GridLayout13_16);
            app.BaseGainLabel.Text = {'Base'; 'Gain'};
            app.TabGroup = uitabgroup(app.GridLayout7);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout2 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Output')
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.GridLayout10 = uigridlayout(app.HistoryTab);
            app.TextArea = uitextarea(app.GridLayout10);
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.PanelInput1 = uipanel(app.GridLayout8);
            app.PanelInput1.Title = 'Input1';
            app.GridLayout9 = uigridlayout(app.PanelInput1);
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.K1Slider = uislider(app.GridLayout11);
            app.K1Slider.Limits = [0 10];
            app.K1Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K1Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.K1Gain = uieditfield(app.GridLayout13, 'numeric');
            app.K1Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K1Gain.Value = 1;
            app.K1Label = uilabel(app.GridLayout13);
            app.K1Label.Text = 'K1';
            app.GridLayout11_2 = uigridlayout(app.GridLayout9);
            app.Wn1Slider = uislider(app.GridLayout11_2);
            app.Wn1Slider.Limits = [0 10];
            app.Wn1Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn1Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_5 = uigridlayout(app.GridLayout11_2);
            app.Wn1Gain = uieditfield(app.GridLayout13_5, 'numeric');
            app.Wn1Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn1Gain.Value = 1;
            app.Wn1Label = uilabel(app.GridLayout13_5);
            app.Wn1Label.Text = 'Wn1';
            app.GridLayout11_3 = uigridlayout(app.GridLayout9);
            app.Td1Slider = uislider(app.GridLayout11_3);
            app.Td1Slider.Limits = [0 60];
            app.Td1Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td1Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_7 = uigridlayout(app.GridLayout11_3);
            app.Td1Gain = uieditfield(app.GridLayout13_7, 'numeric');
            app.Td1Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td1Gain.Value = 1;
            app.Td1Label = uilabel(app.GridLayout13_7);
            app.Td1Label.Text = 'Td1';
            app.GridLayout11_4 = uigridlayout(app.GridLayout9);
            app.Z1Slider = uislider(app.GridLayout11_4);
            app.Z1Slider.Limits = [0 10];
            app.Z1Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z1Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_6 = uigridlayout(app.GridLayout11_4);
            app.Z1Gain = uieditfield(app.GridLayout13_6, 'numeric');
            app.Z1Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z1Gain.Value = 1;
            app.Z1Label = uilabel(app.GridLayout13_6);
            app.Z1Label.Text = 'Z1';
            app.GridLayout11_13 = uigridlayout(app.GridLayout9);
            app.NonlinearFunctionEditFieldLabel = uilabel(app.GridLayout11_13);
            app.NonlinearFunctionEditFieldLabel.Text = {'Non-linear'; 'Function'};
            app.NonlinearFunction1 = uieditfield(app.GridLayout11_13, 'text');
            app.NonlinearFunction1.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NonlinearFunction1.Value = '1';
            app.UIAxes4 = uiaxes(app.GridLayout9);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.PanelInput2 = uipanel(app.GridLayout8);
            app.PanelInput2.Title = 'Input1';
            app.GridLayout9_2 = uigridlayout(app.PanelInput2);
            app.GridLayout11_14 = uigridlayout(app.GridLayout9_2);
            app.K2Slider = uislider(app.GridLayout11_14);
            app.K2Slider.Limits = [0 10];
            app.K2Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K2Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_8 = uigridlayout(app.GridLayout11_14);
            app.K2Gain = uieditfield(app.GridLayout13_8, 'numeric');
            app.K2Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K2Gain.Value = 1;
            app.K2Label = uilabel(app.GridLayout13_8);
            app.K2Label.Text = 'K2';
            app.GridLayout11_15 = uigridlayout(app.GridLayout9_2);
            app.Wn2Slider = uislider(app.GridLayout11_15);
            app.Wn2Slider.Limits = [0 10];
            app.Wn2Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn2Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_9 = uigridlayout(app.GridLayout11_15);
            app.Wn2Gain = uieditfield(app.GridLayout13_9, 'numeric');
            app.Wn2Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn2Gain.Value = 1;
            app.Wn2Label = uilabel(app.GridLayout13_9);
            app.Wn2Label.Text = 'Wn2';
            app.GridLayout11_16 = uigridlayout(app.GridLayout9_2);
            app.Td2Slider = uislider(app.GridLayout11_16);
            app.Td2Slider.Limits = [0 60];
            app.Td2Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td2Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_10 = uigridlayout(app.GridLayout11_16);
            app.Td2Gain = uieditfield(app.GridLayout13_10, 'numeric');
            app.Td2Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td2Gain.Value = 1;
            app.Td2Label = uilabel(app.GridLayout13_10);
            app.Td2Label.Text = 'Td2';
            app.GridLayout11_17 = uigridlayout(app.GridLayout9_2);
            app.Z2Slider = uislider(app.GridLayout11_17);
            app.Z2Slider.Limits = [0 10];
            app.Z2Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z2Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_11 = uigridlayout(app.GridLayout11_17);
            app.Z2Gain = uieditfield(app.GridLayout13_11, 'numeric');
            app.Z2Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z2Gain.Value = 1;
            app.Z2Label = uilabel(app.GridLayout13_11);
            app.Z2Label.Text = 'Z2';
            app.GridLayout11_24 = uigridlayout(app.GridLayout9_2);
            app.NonlinearFunctionEditFieldLabel_4 = uilabel(app.GridLayout11_24);
            app.NonlinearFunctionEditFieldLabel_4.Text = {'Non-linear'; 'Function'};
            app.NonlinearFunction2 = uieditfield(app.GridLayout11_24, 'text');
            app.NonlinearFunction2.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NonlinearFunction2.Value = '1';
            app.UIAxes4_2 = uiaxes(app.GridLayout9_2);
            title(app.UIAxes4_2, '')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, '')
            app.UIAxes4_2.XTick = [];
            app.PanelInput3 = uipanel(app.GridLayout8);
            app.PanelInput3.Title = 'Input1';
            app.GridLayout9_3 = uigridlayout(app.PanelInput3);
            app.GridLayout11_19 = uigridlayout(app.GridLayout9_3);
            app.K3Slider = uislider(app.GridLayout11_19);
            app.K3Slider.Limits = [0 10];
            app.K3Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K3Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_12 = uigridlayout(app.GridLayout11_19);
            app.K3Gain = uieditfield(app.GridLayout13_12, 'numeric');
            app.K3Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.K3Gain.Value = 1;
            app.K3Label = uilabel(app.GridLayout13_12);
            app.K3Label.Text = 'K3';
            app.GridLayout11_20 = uigridlayout(app.GridLayout9_3);
            app.Wn3Slider = uislider(app.GridLayout11_20);
            app.Wn3Slider.Limits = [0 10];
            app.Wn3Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn3Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_13 = uigridlayout(app.GridLayout11_20);
            app.Wn3Gain = uieditfield(app.GridLayout13_13, 'numeric');
            app.Wn3Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Wn3Gain.Value = 1;
            app.Wn3Label = uilabel(app.GridLayout13_13);
            app.Wn3Label.Text = 'Wn3';
            app.GridLayout11_21 = uigridlayout(app.GridLayout9_3);
            app.Td3Slider = uislider(app.GridLayout11_21);
            app.Td3Slider.Limits = [0 10];
            app.Td3Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td3Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_14 = uigridlayout(app.GridLayout11_21);
            app.Td3Gain = uieditfield(app.GridLayout13_14, 'numeric');
            app.Td3Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Td3Gain.Value = 1;
            app.Td3Label = uilabel(app.GridLayout13_14);
            app.Td3Label.Text = 'Td3';
            app.GridLayout11_22 = uigridlayout(app.GridLayout9_3);
            app.Z3Slider = uislider(app.GridLayout11_22);
            app.Z3Slider.Limits = [0 10];
            app.Z3Slider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z3Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChanged, true);
            app.GridLayout13_15 = uigridlayout(app.GridLayout11_22);
            app.Z3Gain = uieditfield(app.GridLayout13_15, 'numeric');
            app.Z3Gain.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.Z3Gain.Value = 1;
            app.Z3Label = uilabel(app.GridLayout13_15);
            app.Z3Label.Text = 'Z3';
            app.GridLayout11_23 = uigridlayout(app.GridLayout9_3);
            app.NonlinearFunctionEditFieldLabel_3 = uilabel(app.GridLayout11_23);
            app.NonlinearFunctionEditFieldLabel_3.Text = {'Non-linear'; 'Function'};
            app.NonlinearFunction3 = uieditfield(app.GridLayout11_23, 'text');
            app.NonlinearFunction3.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NonlinearFunction3.Value = '1';
            app.UIAxes4_3 = uiaxes(app.GridLayout9_3);
            title(app.UIAxes4_3, '')
            xlabel(app.UIAxes4_3, '')
            ylabel(app.UIAxes4_3, '')
            app.UIAxes4_3.XTick = [];
            app.ContextMenu = uicontextmenu(app.TransferFunctionSystemIdentificationUIFigure);
            % Assign app.ContextMenu
            app.GridLayout6.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.TransferFunctionSystemIdentificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYSTEM_IDENTIFICATION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransferFunctionSystemIdentificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransferFunctionSystemIdentificationUIFigure)
        end
    end
end